classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ValuesinppmLabel     matlab.ui.control.Label
        AnalyzeFaultsButton  matlab.ui.control.Button
        ResultTextArea       matlab.ui.control.TextArea
        ResultLabel          matlab.ui.control.Label
        CO2EditField         matlab.ui.control.NumericEditField
        CO2EditFieldLabel    matlab.ui.control.Label
        COEditField          matlab.ui.control.NumericEditField
        COEditFieldLabel     matlab.ui.control.Label
        C2H2EditField        matlab.ui.control.NumericEditField
        C2H2EditFieldLabel   matlab.ui.control.Label
        C2H4EditField        matlab.ui.control.NumericEditField
        C2H4EditFieldLabel   matlab.ui.control.Label
        C2H6EditField        matlab.ui.control.NumericEditField
        C2H6EditFieldLabel   matlab.ui.control.Label
        CH4EditField         matlab.ui.control.NumericEditField
        CH4EditFieldLabel    matlab.ui.control.Label
        H2EditField          matlab.ui.control.NumericEditField
        H2EditFieldLabel     matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeFaultsButton
        function AnalyzeFaultsButtonPushed(app, event)
    % ---------------------------------------------------------
    % 1. GET INPUTS
    % ---------------------------------------------------------
    h2 = app.H2EditField.Value;
    ch4 = app.CH4EditField.Value;
    c2h6 = app.C2H6EditField.Value;
    c2h4 = app.C2H4EditField.Value;
    c2h2 = app.C2H2EditField.Value;
    co = app.COEditField.Value;
    co2 = app.COEditField.Value;

    % Initialize Output Text
    final_report = "===== DGA DIAGNOSTIC REPORT =====" + newline + newline;

    % ---------------------------------------------------------
    % 2. TDCG ANALYSIS
    % ---------------------------------------------------------
    tdcg = h2 + ch4 + c2h6 + c2h4 + c2h2 + co;
    final_report = final_report + sprintf("Total Dissolved Combustible Gas: %.0f ppm\n", tdcg);
    
    if tdcg < 720
        status = "Condition 1: Normal";
    elseif tdcg <= 1920
        status = "Condition 2: Caution (Watch Trends)";
    elseif tdcg <= 4630
        status = "Condition 3: High Risk";
    else
        status = "Condition 4: IMMEDIATE ACTION REQUIRED";
    end
    final_report = final_report + "Status: " + status + newline + newline;

    % ---------------------------------------------------------
    % 3. ROGERS RATIO METHOD
    % ---------------------------------------------------------
    r1 = NaN; r2 = NaN; r5 = NaN;
    if c2h4 > 0, r1 = c2h2/c2h4; end
    if h2 > 0,   r2 = ch4/h2;   end
    if c2h6 > 0, r5 = c2h4/c2h6; end
    
    final_report = final_report + "---> Rogers Ratio Method <---" + newline;
    final_report = final_report + sprintf("R1: %.2f | R2: %.2f | R5: %.2f\n", r1, r2, r5);

    if isnan(r1) || isnan(r2) || isnan(r5)
        rr_res = "Warning: Invalid Data (Zero Division)";
    elseif (r1 < 0.1) && (r2 >= 0.1 && r2 <= 1.0) && (r5 < 1.0)
        rr_res = "Normal working condition";
    elseif (r1 < 0.1) && (r2 < 0.1) && (r5 < 1.0)
        rr_res = "Possible Partial Discharge";
    elseif (r1 >= 0.1 && r1 <= 3.0) && (r2 >= 0.1 && r2 <= 1.0) && (r5 > 3.0)
        rr_res = "High-energy Discharge (Arcing)";
    elseif (r1 < 0.1) && (r2 >= 0.1 && r2 <= 1.0) && (r5 >= 1.0 && r5 <= 3.0)
        rr_res = "Low Temp Thermal Fault (<300C)";
    elseif (r1 < 0.1) && (r2 > 1.0) && (r5 >= 1.0 && r5 <= 3.0)
        rr_res = "Medium Temp Thermal Fault (300-700C)";
    elseif (r1 < 0.1) && (r2 > 1.0) && (r5 > 3.0)
        rr_res = "High Temp Thermal Fault (>700C)";
    else
        rr_res = "Undetermined (No standard match)";
    end
    final_report = final_report + "Diagnosis: " + rr_res + newline + newline;

    % ---------------------------------------------------------
    % 4. KEY GAS METHOD
    % ---------------------------------------------------------
   final_report = final_report + "---> Key Gas Method <---" + newline;
    
    % Calculating percentages relative to Total Dissolved Combustible Gas (TDCG)
    if tdcg > 0
        p_h2   = (h2 / tdcg) * 100;
        p_ch4  = (ch4 / tdcg) * 100;
        p_c2h6 = (c2h6 / tdcg) * 100;
        p_c2h4 = (c2h4 / tdcg) * 100;
        p_c2h2 = (c2h2 / tdcg) * 100;
        p_co   = (co / tdcg) * 100;
    else
        p_h2=0; p_ch4=0; p_c2h6=0; p_c2h4=0; p_c2h2=0; p_co=0;
    end
    
    kg_result = "Normal / Inconclusive";
    
    % ARCING (Key Gas: Acetylene)
    if (p_c2h2 >= 5) && (c2h2 > 0)
        kg_result = "High-Energy Discharge (Arcing) [Key Gas: Acetylene]";
    % THERMAL OIL FAULTS (Key Gas: Ethylene)
    elseif (p_c2h4 > 20) && (c2h4 > c2h6)
        kg_result = "Thermal Fault in Oil (>700 C) [Key Gas: Ethylene]";
    % CELLULOSE DEGRADATION (Key Gas: CO)
    elseif (p_co > 80)
        kg_result = "Overheating of Insulation Paper [Key Gas: Carbon Monoxide]";
    % LOW-TEMP THERMAL (Key Gas: Methane/Ethane)
    elseif (p_c2h6 > p_ch4) && (p_c2h6 > p_c2h4)
        kg_result = "Low-temp Thermal / Oil Decomposition [Key Gas: Ethane]";
    elseif (p_ch4 > p_h2) && (p_ch4 > p_c2h4)
        kg_result = "Low-temp Thermal (<300 C) [Key Gas: Methane]";
    % PARTIAL DISCHARGE (Key Gas: Hydrogen)
    elseif (p_h2 > p_ch4) && (p_h2 > p_c2h4)
        kg_result = "Partial Discharge (Corona) [Key Gas: Hydrogen]";
    end
    
    final_report = final_report + "Diagnosis: " + kg_result + newline + newline;

    % ---------------------------------------------------------
    % 5. CO2/CO RATIO ANALYSIS (Added Block)
    % ---------------------------------------------------------
    final_report = final_report + "---> CO2/CO Ratio Analysis <---" + newline;
    if co > 0
        ratio = co2/co;
        final_report = final_report + sprintf("Ratio: %.2f\n", ratio);
        
        if ratio < 3
            co_res = "Severe paper degradation (Carbonization)";
        elseif ratio > 10
            co_res = "Normal paper aging / Mild overheating";
        else
            co_res = "Healthy insulation / Standard operation";
        end
    else
        co_res = "N/A (CO is zero)";
    end
    final_report = final_report + "Diagnosis: " + co_res + newline + newline;

    % ---------------------------------------------------------
    % 6. DUVAL TRIANGLE DIAGNOSIS (State-Based Logic)
    % ---------------------------------------------------------
    final_report = final_report + "---> Duval Triangle 1 Method <---" + newline;
    gas_sum = ch4 + c2h4 + c2h2;
    
    if gas_sum > 0
        p_ch4 = (ch4/gas_sum) * 100; 
        p_c2h4 = (c2h4/gas_sum) * 100; 
        p_c2h2 = (c2h2/gas_sum) * 100; 
        
        % --- Creating states ---
        if (p_ch4 < 98), ch4_state = 0; else, ch4_state = 1; end

        if (p_c2h2 < 4), c2h2_state = 0;
        elseif (p_c2h2 >= 4 && p_c2h2 < 13), c2h2_state = 1; 
        elseif (p_c2h2 >= 13 && p_c2h2 < 15), c2h2_state = 2;
        elseif (p_c2h2 >= 15 && p_c2h2 < 29), c2h2_state = 3;
        else, c2h2_state = 4;
        end

        if (p_c2h4 < 20), c2h4_state = 0;
        elseif (p_c2h4 >= 20 && p_c2h4 < 23), c2h4_state = 1;
        elseif (p_c2h4 >= 23 && p_c2h4 < 40), c2h4_state = 2;
        elseif (p_c2h4 >= 40 && p_c2h4 < 50), c2h4_state = 3;
        else, c2h4_state = 4;
        end
        
        % --- Finding Faults ---
        fault = 'Undetermined / Boundary Case';
        if (ch4_state == 1)
            fault = 'PD: Partial Discharge';
        elseif (ch4_state == 0) && (c2h2_state == 2 || c2h2_state == 3 || c2h2_state == 4) && (c2h4_state == 0 || c2h4_state == 1)
            fault = 'D1: Low energy discharge (Sparking)';
        elseif (ch4_state == 0) && (c2h2_state == 2 || c2h2_state == 3) && (c2h4_state == 2)
            fault = 'D2: High energy discharge (Arcing)';
        elseif (ch4_state == 0) && (c2h2_state == 4) && (c2h4_state == 2 || c2h4_state == 3 || c2h4_state == 4)
            fault = 'D2: High energy discharge (Arcing)';
        elseif (ch4_state == 0) && (c2h2_state == 0) && (c2h4_state == 0)
            fault = 'T1: Low Temperature Thermal Fault (<300C)';
        elseif (ch4_state == 0) && (c2h2_state == 0) && (c2h4_state == 1 || c2h4_state == 2 || c2h4_state == 3)
            fault = 'T2: Medium Temperature Thermal Fault (300-700C)';
        elseif (ch4_state == 0) && (c2h2_state == 0 || c2h2_state == 1 || c2h2_state == 2) && (c2h4_state == 4)
            fault = 'T3: High Temperaure Thermal Fault (>700C)';
        elseif (ch4_state == 0) && (c2h2_state == 1) && (c2h4_state == 0 || c2h4_state == 1 || c2h4_state == 2 || c2h4_state == 3)
            fault = 'DT: Mix of thermal and electrical faults';
        elseif (ch4_state == 0) && (c2h2_state == 2) && (c2h4_state == 3)
            fault = 'DT: Mix of thermal and electrical faults';
        elseif (ch4_state == 0) && (c2h2_state == 3) && (c2h4_state == 3 || c2h4_state == 4)
            fault = 'DT: Mix of thermal and electrical faults';
        end
        
    else
        fault = "N/A (Gas sum is 0)";
    end
    
    final_report = final_report + "Diagnosis: " + string(fault) + newline + newline;
    % ---------------------------------------------------------
    % 5. UPDATE TEXT AREA
    % ---------------------------------------------------------
    app.ResultTextArea.Value = final_report;

    % ---------------------------------------------------------
    % 6. DUVAL TRIANGLE 1 PLOT
    % ---------------------------------------------------------
    
    % --- Calculation ---
    gas_sum = ch4 + c2h4 + c2h2;
    if gas_sum > 0
        p_ch4 = (ch4/gas_sum); 
        p_c2h4 = (c2h4/gas_sum);
        
        % Cartesian Coordinates for Plot
        X_pt = ((p_c2h4 / 0.866) + (p_ch4 / 1.732)) * 0.866;
        Y_pt = p_ch4 * 0.866;
    else
        X_pt = -1; Y_pt = -1; % Hide point if sum is 0
    end
    
    % --- Clear & Setup Axes ---
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
    axis(app.UIAxes, 'off');
    
    % --- Zone Coordinates ---
    % Main Triangle Vertices
    top = [0.5, 0.866]; right = [1, 0]; left = [0, 0];
    
    % Internal Points
    a=[0.49, 0.849]; b=[0.51, 0.849]; c=[0.48, 0.831]; d=[0.58, 0.658];
    e=[0.6, 0.693]; f=[0.73, 0.398]; g=[0.75, 0.433]; h=[0.675, 0.303];
    I=[0.85, 0]; J=[0.71, 0]; k=[0.555, 0.268]; l=[0.635, 0.407];
    m=[0.55, 0.554]; n=[0.435, 0.753]; o=[0.23, 0];

    % Defining Polygons
    PD_x = [top(1), b(1), a(1)]; PD_y = [top(2), b(2), a(2)];
    T1_x = [a(1), b(1), e(1), d(1), c(1)]; T1_y = [a(2), b(2), e(2), d(2), c(2)];
    T2_x = [d(1), e(1), g(1), f(1)]; T2_y = [d(2), e(2), g(2), f(2)];
    T3_x = [f(1), g(1), right(1), I(1), h(1)]; T3_y = [f(2), g(2), right(2), I(2), h(2)];
    D1_x = [n(1), m(1), o(1), left(1)]; D1_y = [n(2), m(2), o(2), left(2)];
    D2_x = [m(1), l(1), k(1), J(1), o(1)]; D2_y = [m(2), l(2), k(2), J(2), o(2)];
    DT_x = [c(1), d(1), f(1), h(1), I(1), J(1), k(1), l(1), m(1), n(1)];
    DT_y = [c(2), d(2), f(2), h(2), I(2), J(2), k(2), l(2), m(2), n(2)];

    % --- Colored Patches ---
    fill(app.UIAxes, PD_x, PD_y, [0.8500 0.9500 1.000], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
    fill(app.UIAxes, T1_x, T1_y, [1.0000 0.6824 0.6863], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
    fill(app.UIAxes, T2_x, T2_y, [1.0000 0.8000 0.0000], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
    fill(app.UIAxes, T3_x, T3_y, [0.2471 0.2471 0.2471], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
    fill(app.UIAxes, D1_x, D1_y, [0.0000 0.8118 0.8784], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
    fill(app.UIAxes, D2_x, D2_y, [0.1490 0.3216 0.6549], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
    fill(app.UIAxes, DT_x, DT_y, [0.8314 0.3255 0.6392], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);

    % --- Zone Labels ---
    text(app.UIAxes, 0.5, 0.855, 'PD', 'FontSize', 8, 'HorizontalAlignment', 'center');
    text(app.UIAxes, 0.55, 0.75, 'T1', 'FontSize', 8, 'HorizontalAlignment', 'center');
    text(app.UIAxes, 0.65, 0.55, 'T2', 'FontSize', 8, 'HorizontalAlignment', 'center');
    text(app.UIAxes, 0.85, 0.20, 'T3', 'FontSize', 8, 'HorizontalAlignment', 'center');
    text(app.UIAxes, 0.25, 0.20, 'D1', 'FontSize', 8, 'HorizontalAlignment', 'center');
    text(app.UIAxes, 0.45, 0.20, 'D2', 'FontSize', 8, 'HorizontalAlignment', 'center');
    text(app.UIAxes, 0.65, 0.35, 'DT', 'FontSize', 8, 'HorizontalAlignment', 'center');

    % --- User Data Point ---
    if gas_sum > 0
        plot(app.UIAxes, X_pt, Y_pt, 'ko', 'MarkerSize', 4, 'MarkerFaceColor', 'r', 'LineWidth', 1.5);
        text(app.UIAxes, X_pt, Y_pt+0.05, 'FAULT', 'Color', 'r', 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
    end
    
    % --- Corner Labels ---
    text(app.UIAxes, 0.5, 0.92, '100% CH4', 'HorizontalAlignment', 'center');
    text(app.UIAxes, -0.05, 0, '100% C2H2', 'HorizontalAlignment', 'right');
    text(app.UIAxes, 1.05, 0, '100% C2H4', 'HorizontalAlignment', 'left');

    hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.H2EditFieldLabel = uilabel(app.UIFigure);
            app.H2EditFieldLabel.Text = 'H2';
            app.H2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CH4EditFieldLabel = uilabel(app.UIFigure);
            app.CH4EditFieldLabel.Text = 'CH4';
            app.CH4EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2H6EditFieldLabel = uilabel(app.UIFigure);
            app.C2H6EditFieldLabel.Text = 'C2H6';
            app.C2H6EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2H4EditFieldLabel = uilabel(app.UIFigure);
            app.C2H4EditFieldLabel.Text = 'C2H4';
            app.C2H4EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2H2EditFieldLabel = uilabel(app.UIFigure);
            app.C2H2EditFieldLabel.Text = 'C2H2';
            app.C2H2EditField = uieditfield(app.UIFigure, 'numeric');
            app.COEditFieldLabel = uilabel(app.UIFigure);
            app.COEditFieldLabel.Text = 'CO';
            app.COEditField = uieditfield(app.UIFigure, 'numeric');
            app.CO2EditFieldLabel = uilabel(app.UIFigure);
            app.CO2EditFieldLabel.Text = 'CO2';
            app.CO2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.AnalyzeFaultsButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeFaultsButtonPushed, true);
            app.AnalyzeFaultsButton.Text = 'Analyze Faults';
            app.ValuesinppmLabel = uilabel(app.UIFigure);
            app.ValuesinppmLabel.Text = 'Values in ppm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end